classdef secret_santa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        target_name         matlab.ui.control.EditField
        gettargetButton     matlab.ui.control.Button
        generatevalsButton  matlab.ui.control.Button
        input_box           matlab.ui.control.NumericEditField
        finishsetupButton   matlab.ui.control.Button
        yournameLabel       matlab.ui.control.Label
        yournumberLabel     matlab.ui.control.Label
        confirmButton       matlab.ui.control.Button
        user_name_field     matlab.ui.control.EditField
        user_number_field   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        assignement_matrix = zeros(1,8);
        user_name_matrix = ["" "" "" "" "" "" "" ""];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: gettargetButton
        function get_targetButtonPushed(app, event)
            app.target_name.Value = app.user_name_matrix(app.assignement_matrix(app.input_box.Value));
            pause(1);
            app.target_name.Value = "";
            app.input_box.Value = 0;
        end

        % Button pushed function: generatevalsButton
        function generatevalsButtonPushed(app, event)
            a = 1:1:8;
            app.assignement_matrix = a(randperm(length(a)));
            disp([1:1:8;app.assignement_matrix]);
            while any(app.assignement_matrix == a) 
                app.assignement_matrix = a(randperm(length(a)));
                disp([1:1:8;app.assignement_matrix]);
            end
            disp("end")
            app.generatevalsButton.BackgroundColor = "g";
        end

        % Button pushed function: confirmButton
        function confirmButtonPushed(app, event)
            app.user_name_matrix(app.user_number_field.Value) = app.user_name_field.Value;
            app.user_number_field.Value = 0;
            app.user_name_field.Value = " ";
        end

        % Button pushed function: finishsetupButton
        function finishsetupButtonPushed(app, event)
            app.confirmButton.Enable = "off";
            app.generatevalsButton.Enable = "on";
            app.input_box.Enable = "on";
            app.gettargetButton.Enable = "on";
            app.finishsetupButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.user_number_field = uieditfield(app.GridLayout, 'numeric');
            app.user_number_field.Limits = [0 8];
            app.user_number_field.Value = 1;
            app.user_name_field = uieditfield(app.GridLayout, 'text');
            app.confirmButton = uibutton(app.GridLayout, 'push');
            app.confirmButton.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirmButton.Text = 'confirm';
            app.yournumberLabel = uilabel(app.GridLayout);
            app.yournumberLabel.Text = 'your number->';
            app.yournameLabel = uilabel(app.GridLayout);
            app.yournameLabel.Text = 'your name->';
            app.finishsetupButton = uibutton(app.GridLayout, 'push');
            app.finishsetupButton.ButtonPushedFcn = createCallbackFcn(app, @finishsetupButtonPushed, true);
            app.finishsetupButton.Text = 'finish setup';
            app.input_box = uieditfield(app.GridLayout, 'numeric');
            app.input_box.Limits = [0 8];
            app.input_box.Enable = 'off';
            app.input_box.Value = 1;
            app.generatevalsButton = uibutton(app.GridLayout, 'push');
            app.generatevalsButton.ButtonPushedFcn = createCallbackFcn(app, @generatevalsButtonPushed, true);
            app.generatevalsButton.Enable = 'off';
            app.generatevalsButton.Text = 'generate vals';
            app.gettargetButton = uibutton(app.GridLayout, 'push');
            app.gettargetButton.ButtonPushedFcn = createCallbackFcn(app, @get_targetButtonPushed, true);
            app.gettargetButton.Enable = 'off';
            app.gettargetButton.Text = 'get target';
            app.target_name = uieditfield(app.GridLayout, 'text');
            app.target_name.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = secret_santa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end